classdef DP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FormatButtonGroup       matlab.ui.container.ButtonGroup
        AmbixButton             matlab.ui.control.RadioButton
        FumaButton              matlab.ui.control.RadioButton
        WyznaczaniekierunkurdadwikuznagraniaambisonicznegoLabel  matlab.ui.control.Label
        ControlUpdateFrequency  matlab.ui.control.NumericEditField
        UpdatefrequencyLabel    matlab.ui.control.Label
        ButtonStop              matlab.ui.control.Button
        ButtonPlay              matlab.ui.control.Button
        ButtonLoadFile          matlab.ui.control.Button
        ElevationSlider         matlab.ui.control.Slider
        ElevationSliderLabel    matlab.ui.control.Label
        BaseLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)

        % wykres
        Pax % Polar axes
        fi
        theta

        %update co ileś sekund
        RandTimer

        % odtwarzacz muzyki
        player

        % import pliku audio
        Fs, W, X, Y, Z
        % flag

        % tabelka fi/ theta
        anglesTable
        iteration = 1;

        test = 1;

        isFuma = true;
        format = "fuma"

    end
    
    methods (Access = private)
        
        function RandTimerFcn(app,~,~)

            if (app.test > 20)
                app.test = app.test - 20;
            end
            disp(app.test);
            app.test = app.test + 1;

            a = size(app.anglesTable, 1);
            polarplot(app.Pax, app.anglesTable(app.iteration, 1)/180*pi, app.anglesTable(app.iteration, 3), 'w-o');
            app.ElevationSlider.Value = app.anglesTable(app.iteration, 2);
            rlim(app.Pax, [-80, 0]);
            app.Pax.ThetaZeroLocation = 'top';
            app.iteration = app.iteration + 1;

            get(app.Pax, 'fontname');
            set(app.Pax, 'fontname', 'Bookman Old Style');
            get(app.Pax, 'fontWeight');
            set(app.Pax, 'fontWeight', 'normal');
            get(app.Pax, 'ThetaColor');
            set(app.Pax, 'ThetaColor', [1.00,1.00,1.00]);
            get(app.Pax, 'RColor');
            set(app.Pax, 'RColor', [1.00,1.00,1.00]);
            get(app.Pax, 'Color');
            set(app.Pax, 'Color', [0.39, 0.42, 0.69]);
            get(app.Pax, 'GridColorMode');
            set(app.Pax, 'GridColorMode', 'manual');
            get(app.Pax, 'GridColor');
            set(app.Pax, 'GridColor', [1.00, 1.00, 1.00]);
            

            if (app.iteration > a)
                stop(app.RandTimer)
                app.iteration = 1;
            end
            
        end    

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [260 35 350 350];
            app.Pax.ThetaZeroLocation = 'top';
            rlim(app.Pax, [-80, 0]);
            
            get(app.Pax, 'fontname');
            set(app.Pax, 'fontname', 'Bookman Old Style');
            get(app.Pax, 'fontWeight');
            set(app.Pax, 'fontWeight', 'normal');
            get(app.Pax, 'ThetaColor');
            set(app.Pax, 'ThetaColor', [1.00,1.00,1.00]);
            get(app.Pax, 'RColor');
            set(app.Pax, 'RColor', [1.00,1.00,1.00]);
            get(app.Pax, 'Color');
            set(app.Pax, 'Color', [0.39, 0.42, 0.69]);
            get(app.Pax, 'GridColorMode');
            set(app.Pax, 'GridColorMode', 'manual');
            get(app.Pax, 'GridColor');
            set(app.Pax, 'GridColor', [1.00, 1.00, 1.00]);
            %app.Pax.ThetaDir = 'clockwise';

        end

        % Value changed function: ElevationSlider
        function ElevationSliderValueChanged(app, event)
            % app.theta = app.ElevationSlider.Value;
            
        end

        % Button pushed function: ButtonLoadFile
        function ButtonLoadFilePushed(app, event)
            [filename, pathname] = uigetfile("*.wav");
            fileLocation = string(pathname) + string(filename);
            [y, app.Fs] = audioread(fileLocation);

            % definicja poszczególnych kanałów
            if app.isFuma == true
                app.format = "fuma-fuma";
                app.W = y(:, 1);
                app.X = y(:, 2);
                app.Y = y(:, 3);
                app.Z = y(:, 4);
            else
                app.format = "acn-sn3d";
                app.W = y(:, 1);
                app.X = y(:, 4);
                app.Y = y(:, 2);
                app.Z = y(:, 3);
            end

            % binauralAudio = getBinaural(y, app.Fs, app.format);
            binauralAudio = getBinaural(y, app.Fs);
            app.player = audioplayer(binauralAudio, 48000);
            % app.player = audioplayer(y(:, 1:2), app.Fs);


        end

        % Button pushed function: ButtonPlay
        function ButtonPlayPushed(app, event)
            
            n = length(app.W);
            updateTime = 1 / app.ControlUpdateFrequency.Value;

            % natężenie na kierunkach
            %podział na okna + 0

            fileLength = length(app.W)/app.Fs;
            if fileLength >= updateTime
                if mod(fileLength, updateTime) == 0
                    fileLengthPadded = fileLength;
                else
                    fileLengthPadded = fileLength + updateTime - mod(fileLength, updateTime);
                end
            else
                fileLengthPadded = updateTime;
            end

            windowCount = fileLengthPadded / updateTime;
            windowLength = fileLengthPadded * app.Fs / windowCount;
            ceilDifference = fileLengthPadded * app.Fs;

            for jj = n+1:ceilDifference
                app.W(jj) = 0;
                app.X(jj) = 0;
                app.Y(jj) = 0;
                app.Z(jj) = 0;
            end
            
            currentWindow = 1;
            windowStartSample = 1;
            windowEndSample = windowLength;

            for n2 = 1:windowCount
    
                % obliczanie natężenia w oknie
                Ix=(sum(app.X(windowStartSample:windowEndSample).*app.W(windowStartSample:windowEndSample)))/windowLength;
                Iy=(sum(app.Y(windowStartSample:windowEndSample).*app.W(windowStartSample:windowEndSample)))/windowLength;
                Iz=(sum(app.Z(windowStartSample:windowEndSample).*app.W(windowStartSample:windowEndSample)))/windowLength;

                ItotalLevel = 10 * log10(sqrt(abs(Ix).^2 + abs(Iy).^2 + abs(Iz).^2));
    
                % uzupelnianie tabelki
                fiMain = angle(Ix+1i*Iy);
                thetaMain = angle(sqrt((Ix).^2+(Iy).^2)+1i*(Iz));

                app.anglesTable(n2, 1) = fiMain*(180/pi);
                app.anglesTable(n2, 2) = thetaMain*(180/pi);
                app.anglesTable(n2, 3) = ItotalLevel;
                

                %update poczatku i konca okna
                currentWindow = currentWindow + 1;
                windowStartSample = (currentWindow - 1) * windowLength + 1;
                windowEndSample = currentWindow * windowLength;
                
               
            end

            app.iteration = 1;

            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', updateTime, ...        % Period is 0.25 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn, ...   % Specify callback function
                'TasksToExecute', size(app.anglesTable, 1));

            play(app.player);

            start(app.RandTimer);
        end

        % Button pushed function: ButtonStop
        function ButtonStopPushed(app, event)
            stop(app.RandTimer);
            stop(app.player);
        end

        % Selection changed function: FormatButtonGroup
        function FormatButtonGroupSelectionChanged(app, event)
            selectedButton = app.FormatButtonGroup.SelectedObject;
            app.isFuma = not(app.isFuma);
            disp(app.isFuma);
            fr
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Azimuth';
            app.ElevationSliderLabel = uilabel(app.UIFigure);
            app.ElevationSliderLabel.Text = 'Elevation';
            app.ElevationSlider = uislider(app.UIFigure);
            app.ElevationSlider.Limits = [-90 90];
            app.ElevationSlider.ValueChangedFcn = createCallbackFcn(app, @ElevationSliderValueChanged, true);
            app.ButtonLoadFile = uibutton(app.UIFigure, 'push');
            app.ButtonLoadFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadFilePushed, true);
            app.ButtonLoadFile.Icon = fullfile(pathToMLAPP, '12810465181556279520-128.png');
            app.ButtonLoadFile.Text = '';
            app.ButtonPlay = uibutton(app.UIFigure, 'push');
            app.ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlayPushed, true);
            app.ButtonPlay.Icon = fullfile(pathToMLAPP, 'play-removebg-preview-removebg-preview.png');
            app.ButtonPlay.Text = '';
            app.ButtonStop = uibutton(app.UIFigure, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopPushed, true);
            app.ButtonStop.Icon = fullfile(pathToMLAPP, 'stop-removebg-preview.png');
            app.ButtonStop.Text = '';
            app.UpdatefrequencyLabel = uilabel(app.UIFigure);
            app.UpdatefrequencyLabel.Text = 'Frequency';
            app.ControlUpdateFrequency = uieditfield(app.UIFigure, 'numeric');
            app.ControlUpdateFrequency.Limits = [0 Inf];
            app.ControlUpdateFrequency.ValueDisplayFormat = '%11.4g Hz';
            app.ControlUpdateFrequency.Value = 4;
            app.WyznaczaniekierunkurdadwikuznagraniaambisonicznegoLabel = uilabel(app.UIFigure);
            app.WyznaczaniekierunkurdadwikuznagraniaambisonicznegoLabel.Text = 'Determining the direction of a sound source from an ambisonic recording';
            app.FormatButtonGroup = uibuttongroup(app.UIFigure);
            app.FormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FormatButtonGroupSelectionChanged, true);
            app.FormatButtonGroup.Title = 'Format';
            app.FumaButton = uiradiobutton(app.FormatButtonGroup);
            app.FumaButton.Text = 'Fuma';
            app.FumaButton.Value = true;
            app.AmbixButton = uiradiobutton(app.FormatButtonGroup);
            app.AmbixButton.Text = 'Ambix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end